classdef Aggregator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        fNIRsOffsetEditFieldLabel  matlab.ui.control.Label
        fNIRsOffsetEditField       matlab.ui.control.NumericEditField
        EFileButton                matlab.ui.control.Button
        fNIRSFileButton            matlab.ui.control.Button
        CapnometryFileButton       matlab.ui.control.Button
        console                    matlab.ui.control.TextArea
        SubmitButton               matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
        CapOffsetEditFieldLabel    matlab.ui.control.Label
        CapOffsetEditField         matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        fNIRS_table % Table storing fNIRS data
        Capnometry_table % Table storing Cap. date
        Eprime_table % Table storing E' data
        
        fNIRS_set = false; % boolean; true if fNIRS data is saved
        Capnometry_set = false; % boolean; true if the Cap. data is saved
        Eprime_set = false; % boolean; true if the E' data is saved
    end
    
    methods (Access = private)
        % a simple wrapper for logging text to the console textarea
        % in the UI.
        function console_log(app, text)
            app.console.Value = [app.console.Value; text]; % append text
            scroll(app.console, 'bottom') % scroll console to bottom
        end
        
        % stores the given table in the debug table
        function store_table(app, table)
            app.UITable.Data = table;
            
            % store column names
            app.UITable.ColumnName = table.Properties.VariableNames;
        end
        
        % chooses a file and passes it to the debug table
        function table = choose_file(app, filter, name, error)
            [file, path] = uigetfile(filter, name);
            % the above line will construct a file chooser
            
            if isequal(file, 0)
                console_log(app, error)
                table = 0;
                return;
            end
            
            filename = append(path, file);
            
            % set options to import the datetime properly
            % because some files are chart sheets, it's possible
            % for them to fail with the standard detectImport
            % only the chartsheets have issues with datetime
            % formatting.
            opts = [];
            try
                opts = detectImportOptions(filename);
            catch ME
                opts = detectImportOptions(filename, 'Sheet', 'Data');
                opts = setvartype(opts, {'Time'}, {'datetime'});
                opts = setvaropts(opts,'Time', ...
                        'DatetimeFormat','H:mm:ss.SS',...
                        'FillValue','now');
            end
            
            try
                table = readtable(filename, opts);
            catch ME
                table = readtable(filename, 'Sheet', 'Data', opts);
            end
            
            % store table visibly
            store_table(app, table);
            
            figure(app.UIFigure);
        end
        
        % rounds all values in the given column to the nearest 0.25
        % returned as an array rather than a table
        function out = round225(~, col)
            % col -> array
            col = table2array(col);
            
            out = round(col * 4) / 4;
        end
        
        % constructs a table with the size of the fnirs and
        % capnometry placed side by side (using the height of the
        % capnometry data), with the appropriate headers
        function table = construct_table(~, fnirs, cap)
            w = width(fnirs) + width(cap);
            h = height(cap);
            headers = [cap.Properties.VariableNames ...
                fnirs.Properties.VariableNames];
            
            table = cell2table(cell(h, w), 'VariableNames', headers);
        end
        
        function out = compare_times(~, t1, t2)
            out = isequal(string(t1), string(t2));
        end
        
        % gets the previous time stamp in the fnirs data. If 
        % there is no previous time stamp, 0 is returned.
        function prev = get_previous(~, fnirs, cur_ind)
            if (cur_ind == 1)
                prev = 0;
                return;
            end
            
            prev = fnirs(cur_ind - 1);
        end
        
        % extracts and averages a grouping of rows in between
        % a given min and max.
        function row = average_rows(~, table, mi, ma)
            if (ma > height(table)) 
                error("Capnometry file extends beyond fNIRS.")
            end
            
            rows = table(mi:ma, :);
            
            % the Time column is not averaged
            % but the rest are. The largest Time value is selected
            % the Time_s column is left as 0, to be replaced by
            % the rounded fnirs_timestamps data
            sub_row = [{0}, array2table(mean(rows{:, 2:end}, 1))];
            row = sub_row;
            
            row.Properties.VariableNames = table.Properties.VariableNames;
        end
        
        % combines a row from the capnometry table with a row from 
        % the fnirs data.
        % cap: the capnometry table (full)
        % cur_row: the row to combine with from the fnirs
        % max/min: the timestamps of the current and previous row
        % that indicate the rows to be averaged.
        function row = combine_row(app, cap, cur_row, mi, ma)
            % extract the row indices from the parameters
            mi = mi * 4 + 1;
            ma = ma * 4 + 1;
            
            % average the capnometry data
            avg_cap = average_rows(app, cap, mi, ma);
            
            row = [cur_row avg_cap];
        end
        
        % averaging extra rows in the capnometry, the capnometry
        % is combined with the fNIRs data.
        function out = combine_fnirs_cap(app, fnirs, cap, cap_offset)
            % extract and round the fNIRs timestamps to the nearest
            % 1/4 of a second.
            fnirs_timestamps = fnirs(:, {'Time_s'});
            fnirs_timestamps = round225(app, fnirs_timestamps);
            
            % cut off the extra capnometry data (using the cap_offset)
            % cap_offset * 4 is the number of rows to remove
            % adjust timestamps to start at 00:00:00
            cap = cap((cap_offset * 4 + 1):end, :);
            cap.Time = cap.Time - datestr(datenum(0, 0, 0, 0, 0, ...
                cap_offset), 'HH:MM:SS');
            
            % preallocate out (not really, though)
            out = [];
            
            % combine rows
            for i = 1:length(fnirs_timestamps)
                try 
                    row = combine_row(app, cap, fnirs(i, :), ...
                    get_previous(app, fnirs_timestamps, i), ...
                    fnirs_timestamps(i));
                catch ME
                    break;
                end
                
                out = [out; row];
            end
            
            % format the Time column
            fnirs_timestamps = duration(0, 0, fnirs_timestamps);
            fnirs_timestamps.Format = 'hh:mm:ss.SS';
            
            % set Time (from capnometry) column
            out.Time = fnirs_timestamps;
            console_log(app, 'fNIRs and Capnometry aggregated.')
        end
        
        % finds the nearest date to the eprime row given, then returns
        % a table with the proper eprime rows filled in up to (and 
        % including) that row. This will then be appended vertically
        % in the parent function.
        function out = nearest_date(~, comb, comb_index, eprime, eprev)
            out = [];
            if (eprime.Timestamp(1) < comb.Timestamp(1))
                return;
            end
            
            validColumns = comb.Timestamp(comb_index:end);
            [~, idx] = min(abs(eprime.Timestamp(1) - validColumns));
            
            if idx > 1
                for i = 1:(idx - 1)
                    out = [out; eprev];
                end
            end
            
            out = [out; eprime(:, {'BlockOrder', 'EventName'})];
        end
        
        % combines the eprime data with the fnirs and capnometry.
        % this is done in the following way:
        % 1) Subtract the offset (in seconds) from the Timestamp
        %    column in the fnirs/capnometry data
        % 2) Iterate through timestamps of the Eprime file
        %    and match them with timestamps from the fnirs/cap.
        %    2a) if the stamps match, place that row.
        %    2b) if the stamps don't match, place the previous row
        %    2*) a "row" from E' is {'BlockOrder', 'EventName'}
        % 3) Add that column to the fnirs/capnometry table and return
        %
        % A note for any programmers reading this: I'm really really
        % bad at MATLAB, so I'm really just going for functional here.
        % However, I do know that the program would be more efficient
        % if the two for loops were combined. I'm just lazy, since this
        % will still work fairly quickly. I also don't get preallocation,
        % but most of the loops supposedly could use it for the 
        % tables they adjust, which would also improve speed.
        function out = add_eprime(app, comb, eprime, offset)
            console_log(app, 'Adjusting fNIRs by offset of ' ...
                + string(offset) + '.')
            % 1:
            % the Timestamps column is also formatted to not include dates
            timestamps = string(comb.Timestamp);
            for i = 1:length(timestamps)
                temp = strsplit(timestamps(i));
                timestamps(i) = string(temp(2));
            end
            
            timestamps = duration(timestamps);
            
            comb.Timestamp = timestamps - duration(offset);
            
            % 1.5:
            eprime = eprime(~contains(eprime.EventName, "endtrial"), :);
            
            % 2:
            eprime_out = [];
            
            eprimeindex = 1; % the eprime stamp being checked
            eprevrow = eprime(eprimeindex, {'BlockOrder', 'EventName'});
            
            comb_index = 1;
            for i = 1:height(eprime)
                ecurrow = eprime(i, ...
                    {'BlockOrder', 'Timestamp', 'EventName'});
                
                new_rows = nearest_date(app, comb, comb_index, ecurrow, ...
                    eprevrow);
                
                eprime_out = [eprime_out; new_rows];
                comb_index = height(eprime_out) + 1;
                eprevrow = ecurrow(1, {'BlockOrder', 'EventName'});
                
                if (comb_index > height(comb))
                    break;
                end
            end
            
            while (height(eprime_out) < height(comb))
                eprime_out = [eprime_out; eprevrow];
            end
            
            out = [comb eprime_out];
        end
        
        % adds a relative time column to the end of the table
        % this column counts the number of seconds since this
        % event started (starting from 1)
        function out = add_relative_time(~, T)
            counter = 1;
            previous = "";
            out = zeros(height(T), 1);
            
            for i = 1:height(T)
                current = T.EventName(i);
                if isequal(current, previous)
                    counter = counter + 1;
                    out(i) = counter;
                else
                    previous = current;
                    counter = 1;
                    out(i) = counter;
                end
            end
            
            out = array2table(out);
            out.Properties.VariableNames = {'Event Time (s)'};
            out = [ T out ];
        end
        
        % handles the main aggregation of the three files into
        % one consistent file
        function out = aggregate(app, fnirs_offset, cap_offset)
            if not(app.Eprime_set & app.fNIRS_set)
                console_log(app, "Not all files have been chosen.")
                out = 0;
                return; % not all tables are set
            end
            
            fnirs = app.fNIRS_table;
            eprime = app.Eprime_table;
            % the capnometry table has an extra column? Cut off
            
            comb = fnirs;
            
            if (app.Capnometry_set)
                % combine fnirs and capnometry
                comb = combine_fnirs_cap(app, fnirs, ...
                    app.Capnometry_table(:, 1:6), cap_offset);
            end
            
            % adjust fnirs by offset and add eprime data
            out = add_eprime(app, comb, eprime, fnirs_offset);
            
            % add extra eprime columns
            studyid = repelem([eprime.STUDYID(1)], height(out));
            sessiondate = repelem([eprime.SessionDate(1)], height(out));
            starttime = repelem([eprime.Timestamp(1)], height(out));
            
            studyid = table(studyid', 'VariableNames', {'Study ID'});
            sessiondate = table(sessiondate', 'VariableNames', ...
                {'Session Date'});
            starttime = table(starttime', 'VariableNames', ...
                {'Session Time'});
            
            out = [studyid sessiondate starttime out];
            
            % rename columns for ease of use:
            out.Properties.VariableNames{'Time_s'} = 'Time_fNIRs';
            if (app.Capnometry_set)
                out.Properties.VariableNames{'Time'} = 'Time_Cap';
            end
            
            out = add_relative_time(app, out);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EFileButton
        function EFileButtonPushed(app, event)
            console_log(app, "E' File Button Pressed")
            
            T = choose_file(app, {'*.csv'}, ...
                "Select an E' File", "E' File Choosing Canceled");
            
            if (isequal(T, 0))
                return;
            end
            
            app.Eprime_table = T;
            app.Eprime_set = true;
            
            console_log(app, "E' File Chosen")
        end

        % Button pushed function: fNIRSFileButton
        function fNIRSFileButtonPushed(app, event)
            console_log(app, "fNIRS File Button Pressed")
            
            [file, path] = uigetfile("*.mat", "Select an fNIRs file");
            
            % don't let the process use an invalid/cancelled file
            if (isequal(file, 0))
                return;
            end
            
            % store as a property for later use
            app.fNIRS_table = miniCYRIL_handler(file, path);
            app.fNIRS_set = true;
            
            store_table(app, app.fNIRS_table);
            figure(app.UIFigure);
            
            console_log(app, "fNIRS File Chosen")
        end

        % Button pushed function: CapnometryFileButton
        function CapnometryFileButtonPushed(app, event)
            console_log(app, "Capnometry File Button Pressed")
            
            T = choose_file(app, "*.xls", "Select a Capnometry File", ...
                "Capnometry File Choosing Canceled");
            
            if (isequal(T, 0))
                return;
            end
            
            app.Capnometry_table = T;
            app.Capnometry_set = true;
            
            console_log(app, "Capnometry File Chosen")
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            fnirs_offset = app.fNIRsOffsetEditField.Value;
            cap_offset = app.CapOffsetEditField.Value;
            
            fnirs_offset = datestr(datenum(0, 0, 0, 0, 0, fnirs_offset),...
                'HH:MM:SS');
            
            table = aggregate(app, fnirs_offset, cap_offset);
            
            if isequal(table, 0)
                return;
            end
            
            store_table(app, table);
            console_log(app, "Data Aggregated");
            
            % write table to CSV
            [file, path] = uiputfile('*.csv');
            
            if isequal(file, 0)
                return;
            end
            writetable(table, append(path, file));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.fNIRsOffsetEditFieldLabel = uilabel(app.UIFigure);
            app.fNIRsOffsetEditFieldLabel.Text = 'fNIRs Offset';
            app.fNIRsOffsetEditField = uieditfield(app.UIFigure, 'numeric');
            app.fNIRsOffsetEditField.Limits = [-300 300];
            app.fNIRsOffsetEditField.Tooltip = {'Subtracted from the fNIRs data. Positive if fNIRs computer was ahead of the E''.'};
            app.EFileButton = uibutton(app.UIFigure, 'push');
            app.EFileButton.ButtonPushedFcn = createCallbackFcn(app, @EFileButtonPushed, true);
            app.EFileButton.Text = 'E'' File';
            app.fNIRSFileButton = uibutton(app.UIFigure, 'push');
            app.fNIRSFileButton.ButtonPushedFcn = createCallbackFcn(app, @fNIRSFileButtonPushed, true);
            app.fNIRSFileButton.Text = 'fNIRS File';
            app.CapnometryFileButton = uibutton(app.UIFigure, 'push');
            app.CapnometryFileButton.ButtonPushedFcn = createCallbackFcn(app, @CapnometryFileButtonPushed, true);
            app.CapnometryFileButton.Text = {'Capnometry File'; ''};
            app.console = uitextarea(app.UIFigure);
            app.console.Editable = 'off';
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Aggregate';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.CapOffsetEditFieldLabel = uilabel(app.UIFigure);
            app.CapOffsetEditFieldLabel.Text = 'Cap Offset';
            app.CapOffsetEditField = uieditfield(app.UIFigure, 'numeric');
            app.CapOffsetEditField.Limits = [-300 300];
            app.CapOffsetEditField.Tooltip = {'Subtracted from capnometry, the offset between when the capnometry starts recording and the fnirs starts recording'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Aggregator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end