classdef miniCYRIL_at_Columbia < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MINICYRILUIFigure               matlab.ui.Figure
        ReadyLampLabel                  matlab.ui.control.Label
        ReadyLamp                       matlab.ui.control.Lamp
        TabGroup                        matlab.ui.container.TabGroup
        SpectraTab                      matlab.ui.container.Tab
        UIAxes_Spectra                  matlab.ui.control.UIAxes
        ConcentrationsTab               matlab.ui.container.Tab
        UIAxes_Concentration            matlab.ui.control.UIAxes
        DisplayWindowsEditFieldLabel    matlab.ui.control.Label
        DisplayWindowsEditField         matlab.ui.control.NumericEditField
        HbO2Label                       matlab.ui.control.Label
        HbLabel                         matlab.ui.control.Label
        CyCOxLabel                      matlab.ui.control.Label
        CapnometerTab                   matlab.ui.container.Tab
        UIAxes_EtCO2                    matlab.ui.control.UIAxes
        UIAxes_SO2                      matlab.ui.control.UIAxes
        EtCO2Label                      matlab.ui.control.Label
        RespRateLabel                   matlab.ui.control.Label
        SO2Label                        matlab.ui.control.Label
        PulseRateLabel                  matlab.ui.control.Label
        Label_ETCO2_Live                matlab.ui.control.Label
        Label_RR_Live                   matlab.ui.control.Label
        Label_SaO2_Live                 matlab.ui.control.Label
        Label_Pulse_Live                matlab.ui.control.Label
        RealTimeTab                     matlab.ui.container.Tab
        FilterSettingsPanel             matlab.ui.container.Panel
        KalmanFilterPanel               matlab.ui.container.Panel
        UseKalmanFilterCheckBox         matlab.ui.control.CheckBox
        NoiseModelindBLabel             matlab.ui.control.Label
        NoiseModelindBSpinner           matlab.ui.control.Spinner
        BandPassFilterPanel             matlab.ui.container.Panel
        UseLinearfilterCheckBox         matlab.ui.control.CheckBox
        LowPassHzEditFieldLabel         matlab.ui.control.Label
        LowPassHzEditField              matlab.ui.control.NumericEditField
        HighPassHzEditFieldLabel        matlab.ui.control.Label
        HighPassHzEditField             matlab.ui.control.NumericEditField
        CheckBox                        matlab.ui.control.CheckBox
        CheckBox_2                      matlab.ui.control.CheckBox
        SettingsTab                     matlab.ui.container.Tab
        GeneralPanel                    matlab.ui.container.Panel
        IntegrationTimemsEditFieldLabel  matlab.ui.control.Label
        IntegrationTimemsEditField      matlab.ui.control.NumericEditField
        ImportrefButton                 matlab.ui.control.Button
        ReferencespectrawascollectedonTextArea  matlab.ui.control.TextArea
        ReferencespectrawascollectedonTextAreaLabel  matlab.ui.control.Label
        CollectrefButton                matlab.ui.control.Button
        PathlengthPanel                 matlab.ui.container.Panel
        PathlengthMethodButtonGroup     matlab.ui.container.ButtonGroup
        DPFButton                       matlab.ui.control.RadioButton
        NopathlengthButton              matlab.ui.control.RadioButton
        WaterfittingButton              matlab.ui.control.RadioButton
        SeparationcmEditFieldLabel      matlab.ui.control.Label
        SeparationcmEditField           matlab.ui.control.NumericEditField
        DPFDropDownLabel                matlab.ui.control.Label
        DPFDropDown                     matlab.ui.control.DropDown
        WaterFractionEditFieldLabel     matlab.ui.control.Label
        WaterFractionEditField          matlab.ui.control.NumericEditField
        TemperatureWasatchPhotonicsOnlyPanel  matlab.ui.container.Panel
        SetTemperatureCEditFieldLabel   matlab.ui.control.Label
        SetTemperatureCEditField        matlab.ui.control.NumericEditField
        LiveTemperatureCEditFieldLabel  matlab.ui.control.Label
        LiveTemperatureCEditField       matlab.ui.control.NumericEditField
        PathlengthcmEditFieldLabel      matlab.ui.control.Label
        PathlengthcmEditField           matlab.ui.control.NumericEditField
        CorrectionsOceanOpticsOnlyPanel  matlab.ui.container.Panel
        CorrectForDetectorNonlinearityCheckBox  matlab.ui.control.CheckBox
        CorrectForElectricalDarkNoiseCheckBox  matlab.ui.control.CheckBox
        ControlsPanel                   matlab.ui.container.Panel
        StartButton                     matlab.ui.control.Button
        MarkEventButton                 matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        EventDetailsEditFieldLabel      matlab.ui.control.Label
        EventDetailsEditField           matlab.ui.control.EditField
        GroupnameEditFieldLabel         matlab.ui.control.Label
        GroupnameEditField              matlab.ui.control.EditField
        SubjectIDEditFieldLabel         matlab.ui.control.Label
        SubjectIDEditField              matlab.ui.control.EditField
        PreviewButton                   matlab.ui.control.Button
        CommentsTextAreaLabel           matlab.ui.control.Label
        CommentsTextArea                matlab.ui.control.TextArea
        miniCYRILforFAMPATHLabel        matlab.ui.control.Label
        LiveTimesEditFieldLabel         matlab.ui.control.Label
        LiveTimesEditField              matlab.ui.control.NumericEditField
        DataSavedAsEditFieldLabel       matlab.ui.control.Label
        DataSavedAsEditField            matlab.ui.control.EditField
        CapnometerConnectedLampLabel    matlab.ui.control.Label
        CapnometerConnectedLamp         matlab.ui.control.Lamp
        PulseOximeterConnectedLampLabel  matlab.ui.control.Label
        PulseOximeterConnectedLamp      matlab.ui.control.Lamp
        RetryConnectionButton           matlab.ui.control.Button
        EprimeConnectedLampLabel        matlab.ui.control.Label
        EprimeConnectedLamp             matlab.ui.control.Lamp
    end


    properties (Access = public)

        system_info;
        physiol_monitor;
        spectrum_info;
        file_info;
        drawing_handles;
        daq_start_time;
        is_fit_initialized=false;
        stored_data;
        conc_changes;
        DPF;
        optode_dist;
        saving_data;
    end

    properties (Access = private)
        is_ocean_optics=false;
        maxtime=180; %size of data recording buffer
    end

    methods (Access = public)


        function update_temperature(app,varargin)
            if(app.system_info.is_OceanOptics)
                return;
            end
            app.LiveTemperatureCEditField.Value=app.system_info.Spectrometer_driver.TEC_temperature;

            if app.LiveTemperatureCEditField.Value >= -14
                temp_selection = uiconfirm(app.MINICYRILUIFigure,'Be aware that the detector temperature is too high. It is recommended that you stop and wait until temperature reaches -15C. Do you want to continue?','Detector Temperature Warning',...
                    'Icon','warning','Options',{'Continue','Stop'},...
                    'DefaultOption',2,'CancelOption',2);
                if contains(temp_selection,'Stop')==1

                    app.ReadyLamp.Color = [0, 1, 0];
                    app.ReadyLampLabel.Text = 'Ready';
                    return
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function getStarted(app)
            %function that sets defaults once app is opened

            % gathers data off the instrument
            app.system_info=struct('Spectrometer_driver',...
                Spectrometer(true),'local_timer',[],...
                'is_OceanOptics',app.is_ocean_optics,'temp_timer',[]);
            app.spectrum_info=struct('DPF',NaN,'optode_distance',NaN,...
                'reference_spectrum',[],'Abs',struct(),...
                'extinction_coeffs',specific_extinction_coeffs_770to906); % Extinction coefficients: Wavelength, HbO2, HHb, CCO, wavelength dependency
            app.file_info=struct('default_pathname','C:\MiniCyril\Data','output_folder',[],...
                'subject_name','unknown','scan_number',0,'filename',[],'file_fid',-1);

            app.drawing_handles=struct();


            %connect to the physiology monitors
            app.physiol_monitor = Physiol;
            app.RetryConnectionButton.Enable=false;
            app.RetryConnectionButton.Visible=false;
            if(~app.physiol_monitor.pulseox_connected)
%                warning('Pulse Oximeter not found');
                app.PulseOximeterConnectedLampLabel.Text="Pulse Oximeter Not Found";
                app.PulseOximeterConnectedLamp.Color = [1, 0, 0];
                app.RetryConnectionButton.Enable=true;
                app.RetryConnectionButton.Visible=true;
            else
                app.PulseOximeterConnectedLampLabel.Text="Pulse Oximeter Connected";
                app.PulseOximeterConnectedLamp.Color = [0, 1, 0];
            end
            if(~app.physiol_monitor.capno_connected)
                warning('Capnometer not found');
                app.CapnometerConnectedLampLabel.Text="Capnometer Not Found";
                app.CapnometerConnectedLamp.Color=[1,0,0];
                app.RetryConnectionButton.Enable=true;
                app.RetryConnectionButton.Visible=true;
            else
                app.CapnometerConnectedLampLabel.Text="Capnometer Connected";
                app.CapnometerConnectedLamp.Color=[0,1,0];
            end
           

            if isempty(app.spectrum_info.reference_spectrum)
                try
                    ref1 = load(fullfile(app.file_info.default_pathname,'..','ReferenceData','ref.mat'));
                    app.spectrum_info.reference_spectrum = ref1.ref_save;
                    date = ref1.ts(1,:);
                    app.ReferencespectrawascollectedonTextArea.Value = date;
                end
            end

            if(app.system_info.is_OceanOptics)
                set(app.CorrectionsOceanOpticsOnlyPanel.Children,'Enable',true);
                set(app.TemperatureWasatchPhotonicsOnlyPanel.Children,'Enable',false);
                app.LiveTemperatureCEditField.Enable=false;
            else
                set(app.CorrectionsOceanOpticsOnlyPanel.Children,'Enable',false);
                set(app.TemperatureWasatchPhotonicsOnlyPanel.Children,'Enable',true);
                app.LiveTemperatureCEditField.Enable=true;
         %       app.temp_timer = timer('ExecutionMode','fixedRate','Period',10,'TimerFcn',app.update_temperature);
         %       start(app.temp_timer);
            end

            %open spectrometer and set temperature
            app.system_info.Spectrometer_driver.is_OceanOptics=app.system_info.is_OceanOptics;
            app.system_info.Spectrometer_driver.initialize();

            numberOfSpectrometers = length(app.system_info.Spectrometer_driver.system_info);

            if numberOfSpectrometers <= 0
                uialert(app.MINICYRILUIFigure,'No spectrometer found. Check USB connection.','Spectrometer Error')
                app.ReadyLamp.Color = [1, 0, 0];
                app.ReadyLampLabel.Text = 'Not connected';
                return
            end

            % Set the default to the first spectrometer
            app.system_info.Spectrometer_driver.spectrometerIndex=0;
            app.update_instrument();

            [~,app.spectrum_info.wavelengths]=app.system_info.Spectrometer_driver.get_spectrum();

            app.stored_data.raw_data=zeros(length(app.spectrum_info.wavelengths),60*app.maxtime);
            app.stored_data.count=1;
            app.stored_data.conc_data=zeros(3,60*app.maxtime);
            app.stored_data.phys_data={};
            update_optical_params(app,[])

            cla(app.UIAxes_Spectra);
            if(~isempty(app.spectrum_info.reference_spectrum))
                plot(app.UIAxes_Spectra,app.spectrum_info.wavelengths,app.spectrum_info.reference_spectrum,...
                    'color','k','LineStyle','--');
            end
            set(app.MINICYRILUIFigure,'userdata',app)
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %main function when start button pushed - collects data and plots it

          %  lib = lsl_loadlib();
            disp('Resolving a Markers stream...');
            result = {};
            tic;
%             while isempty(result) & toc<5
%                 result = lsl_resolve_byprop(lib,'type','Markers');
%             end
%             if(~isempty(result))
%                 disp('Opening an inlet...');
%         %        app.system_info.inlet = lsl_inlet(result{1});
%                 app.EprimeConnectedLamp.Color=[0 1 0];
%             else
%                 warning("Unable to connect to Eprime");
                 app.system_info.inlet=[];
                 app.EprimeConnectedLamp.Color=[1 0 0];
%             end


            app.ReadyLamp.Color = [0, 0, 1]; %set ready button to blue (running)
            app.ReadyLampLabel.Text = 'Collecting data';

            cla(app.UIAxes_Concentration);
            cla(app.UIAxes_EtCO2);
            cla(app.UIAxes_SO2);
            cla(app.UIAxes_Spectra);

            hold(app.UIAxes_Spectra,'on');
            hold(app.UIAxes_Concentration,'on');
            hold(app.UIAxes_EtCO2,'on');
            hold(app.UIAxes_SO2,'on');

            app.update_instrument();

            % disable the stop
            app.StopButton.Enable=true;
            app.StartButton.Enable=false;
            app.PreviewButton.Enable=false;
            app.MarkEventButton.Enable=true;

            app.saving_data=true;

            try;
                start(app.physiol_monitor);
            catch ME

            end
            if(~app.system_info.is_OceanOptics)
                %check temperature
          %      app.LiveTemperatureCEditField.Value = double(app.system_info.Spectrometer_driver.detectorTemperatureDegC); %display temp

%                 if app.LiveTemperatureCEditField.Value >= -14
% 
%                     temp_selection = uiconfirm(app.MINICYRILUIFigure,'Be aware that the detector temperature is too high. It is recommended that you stop and wait until temperature reaches -15C. Do you want to continue?','Detector Temperature Warning',...
%                         'Icon','warning','Options',{'Continue','Stop'},...
%                         'DefaultOption',2,'CancelOption',2);
% 
%                     if contains(temp_selection,'Stop')==1
%                         app.ReadyLamp.Color = [0, 1, 0];
%                         app.ReadyLampLabel.Text = 'Ready';
%                         return
%                     end
%                 end
            end

            % Setup file info
            app.file_info.subject_name=[app.GroupnameEditField.Value '-Patient' app.SubjectIDEditField.Value];
            date_string = datestr(datetime('now'),30);
            app.file_info.output_folder=fullfile(app.file_info.default_pathname, ...
                app.GroupnameEditField.Value,app.SubjectIDEditField.Value);
            mkdir(app.file_info.output_folder);
            app.file_info.scan_number=app.file_info.scan_number+1;
            app.file_info.filename=[app.file_info.subject_name '_' date_string '_scan' num2str(app.file_info.scan_number) '.txt'];
            set(app.DataSavedAsEditField,'Value',app.file_info.filename);

            app.file_info.file_fid = fopen(fullfile(app.file_info.output_folder,app.file_info.filename),'w+');

            %Add some header info to the front of the file
            fprintf(app.file_info.file_fid,'FAMPATH Data collection file\n');
            fprintf(app.file_info.file_fid,'Collection Date: %s\n',datestr(datetime('now')));
            fprintf(app.file_info.file_fid,'Patient Group: %s\n',app.GroupnameEditField.Value);
            fprintf(app.file_info.file_fid,'Patient ID: %s\n',app.SubjectIDEditField.Value);

            fprintf(app.file_info.file_fid,'Instrument Model: %s (SN %s)\n',...
                app.system_info.Spectrometer_driver.model_name,app.system_info.Spectrometer_driver.model_serial);

            if(app.system_info.is_OceanOptics)
                fprintf(app.file_info.file_fid,'Correct For Detector Nonlinearity: %d\n',app.system_info.Spectrometer_driver.CorrectForDetectorNonlinearity);
                fprintf(app.file_info.file_fid,'Correct For Electrical Dark: %d\n',app.system_info.Spectrometer_driver.CorrectForElectricalDark);
            else
                fprintf(app.file_info.file_fid,'Detector TEC Setpoint DegC: %d\n',app.system_info.Spectrometer_driver.detectorTECSetpointDegC);
            end
            fprintf(app.file_info.file_fid,'Integration Time: %d\n',app.system_info.Spectrometer_driver.IntegrationTime);


            fprintf(app.file_info.file_fid,'\n\n<Wavelengths>');
            [data,wavelengths]=app.system_info.Spectrometer_driver.get_spectrum();
            fprintf(app.file_info.file_fid,'%6.3f',wavelengths);
            fprintf(app.file_info.file_fid,'\n</Wavelengths>');

%             Comments=app.CommentsTextArea.Value;
%             if(~isempty(Comments))
%                 fprintf(app.file_info.file_fid,'\n\n<Comments>');
%                 fprintf(app.file_info.file_fid,'\n%s',Comments);
%                 fprintf(app.file_info.file_fid,'\n</Comments>');
%             end
            
            %TODO - store the reference spectrum here too


            fprintf(app.file_info.file_fid,'\n<DATA>');


            %Initialize all the drawing lines
            app.drawing_handles=struct;
            t=0;
            app.drawing_handles.conc.hbo=plot(app.UIAxes_Concentration,t,0,'color','r','linewidth',2);
            app.drawing_handles.conc.hbr=plot(app.UIAxes_Concentration,t,0,'color','b','linewidth',2);
            app.drawing_handles.conc.cco=plot(app.UIAxes_Concentration,t,0,'color','g','linewidth',2);

            app.drawing_handles.physio.etco2=plot(app.UIAxes_EtCO2,t,0,'color','k','linewidth',2);
            app.drawing_handles.physio.SpO2=plot(app.UIAxes_SO2,t,0,'color','k','linewidth',2);


            app.drawing_handles.spectrum.spectrum = plot(app.UIAxes_Spectra,wavelengths,data,'Color','k','LineWidth',1);

            if(isempty(app.conc_changes))
                app.conc_changes=update_conc_changes(wavelengths);
                app.conc_changes.DPF = app.DPF;
                app.conc_changes.optode_dist=app.PathlengthcmEditField.Value;
                app.conc_changes.use_kalman=true;
                app.conc_changes.raw0=data;
            end

            if(app.is_fit_initialized)
                app.drawing_handles.spectrum.fit_spectrum = plot(app.UIAxes_Spectra,wavelengths,data,'Color','b','LineWidth',1,'linestyle','--');
            end
            xlim(app.UIAxes_Spectra, [600 950])

            app.drawing_handles.tissue.sto2=plot(app.UIAxes_SO2,t,NaN,'Color','k','LineWidth',1);

            app.drawing_handles.capno.etco2=plot(app.UIAxes_EtCO2,t,NaN,'Color','k','LineWidth',1);
            app.drawing_handles.capno.pulse=plot(app.UIAxes_SO2,t,NaN,'Color','k','LineWidth',1);

            app.system_info.timer = timer('ExecutionMode','fixedRate',...
                'Period',app.IntegrationTimemsEditField.Value/1000,'TimerFcn',@mini_CYRIL_update_data);

            app.stored_data.raw_data=zeros(60*app.maxtime,length(app.spectrum_info.wavelengths));
            app.stored_data.count=1;
            app.stored_data.conc_data=zeros(60*app.maxtime,3);
            app.stored_data.phys_data=cell(60*app.maxtime,1);
            app.stored_data.time=zeros(60*app.maxtime,1);
            app.stored_data.events=zeros(60*app.maxtime,1);
            app.daq_start_time=now;
            start(app.system_info.timer);

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)

            stop(app.system_info.timer);
            try
                stop(app.physiol_monitor);
            end
            stop(timerfindall)
            delete(timerfindall)

            app.StopButton.Enable=false;
            app.StartButton.Enable=true;
            app.PreviewButton.Enable=true;
            app.MarkEventButton.Enable=false;
            app.ReadyLamp.Color = [0, 1, 0];
            app.ReadyLampLabel.Text = 'Ready';

            if(app.saving_data)
                % TODO convert TXT file to the same matlab format
                fprintf(app.file_info.file_fid,'</DATA>\n\n');
                fclose(app.file_info.file_fid);

                app.stored_data.time(app.stored_data.count:end)=[];
                app.stored_data.events(app.stored_data.count:end)=[];
                app.stored_data.raw_data(app.stored_data.count:end,:)=[];
                app.stored_data.conc_data(app.stored_data.count:end,:)=[];
                app.stored_data.phys_data={app.stored_data.phys_data{1:app.stored_data.count}};


                Phys.Capno.RespRate=[]; Phys.Capno.ETCO2=[]; Phys.Capno.time=[];
                Phys.Pulse.PulseRate=[]; Phys.Pulse.SpO2=[]; Phys.Pulse.time=[];
                Phys.Cardiac.Waveform=[]; Phys.Cardiac.time=[];
                p=app.stored_data.phys_data;
                for i=1:length(p);
                    if(~isempty(p{i}));
                        Phys.Capno.RespRate=[Phys.Capno.RespRate; p{i}.RespRate];
                        Phys.Capno.ETCO2=[Phys.Capno.ETCO2; p{i}.EtCO2];
                        Phys.Capno.time=[Phys.Capno.time; p{i}.EtCO2_time];
                        Phys.Pulse.PulseRate=[Phys.Pulse.PulseRate; p{i}.PulseRate];
                        Phys.Pulse.SpO2=[Phys.Pulse.SpO2; p{i}.SpO2];
                        Phys.Pulse.time=[Phys.Pulse.time; p{i}.SpO2_time];
                        Phys.Cardiac.Waveform=[Phys.Cardiac.Waveform; p{i}.pulse];
                        Phys.Cardiac.time=[Phys.Cardiac.time; p{i}.time];
                    end;
                end;

               
               scaninfo.date=datestr(datetime('now'));
               scaninfo.group=app.GroupnameEditField.Value;
               scaninfo.patientID=app.SubjectIDEditField.Value;
               scaninfo.instrument.model=app.system_info.Spectrometer_driver.model_name;
               scaninfo.instrument.serial=app.system_info.Spectrometer_driver.model_serial;

            if(app.system_info.is_OceanOptics)
                scaninfo.instrument.nonlinear_correction=app.system_info.Spectrometer_driver.CorrectForDetectorNonlinearity;
                scaninfo.instrument.darknoise_correction=app.system_info.Spectrometer_driver.CorrectForElectricalDark;
            else
                scaninfo.instrument.TEC_setpoint=app.system_info.Spectrometer_driver.detectorTECSetpointDegC;
            end
            scaninfo.instrument.integration_time=app.system_info.Spectrometer_driver.IntegrationTime;

            [data,wavelengths]=app.system_info.Spectrometer_driver.get_spectrum();
            app.stored_data.wavelengths=wavelengths;
            
            Comments=app.CommentsTextArea.Value;
            if(~isempty(Comments))
               scaninfo.Comments=Comments;
            end
            
            reference.spectrum=app.spectrum_info.reference_spectrum;
            reference.wavelengths=wavelengths;
            reference.date_collected=app.ReferencespectrawascollectedonTextArea.Value;
            
            stored_data=app.stored_data;
            stored_data.Phys=Phys;
            stored_data.scaninfo=scaninfo;
            stored_data.reference=reference;
            save(fullfile(app.file_info.output_folder,strtok(app.file_info.filename,'.')),...
                '-STRUCT','stored_data')
            end

        end

        % Callback function
        function InitializeButtonPushed(app, event)
            %acquire n seconds of spectrum and apply broadband fitting to get StO2

            app.file_info.subject_name=[app.GroupnameEditField.Value '-Patient' app.SubjectIDEditField.Value];
            date_string = datestr(datetime('now'),30);
            app.file_info.output_folder=fullfile(app.file_info.default_pathname, ...
                app.GroupnameEditField.Value,app.SubjectIDEditField.Value);
            mkdir(app.file_info.output_folder);
            app.file_info.scan_number=app.file_info.scan_number+1;

            app.ReadyLamp.Color = [0, 0, 1];
            app.ReadyLampLabel.Text = 'Collecting data';

            %open spectrometer
            app.update_instrument();
            app.update_optical_params();
            app.update_temperature();


            [d,wavelength]=app.system_info.Spectrometer_driver.get_spectrum();
            data=zeros(length(d),app.SamplesforInitializationEditField.Value);
            data(:,1)=d;

            cla(app.UIAxes_Spectra);
            app.drawing_handles.spectrum.spectrum = plot(app.UIAxes_Spectra,wavelength,data(:,1),'Color','k','LineWidth',1);
            xlim(app.UIAxes_Spectra, [600 950])
            drawnow;


            for j = 2:app.SamplesforInitializationEditField.Value %loop to collect initial spectra
                pause(app.system_info.Spectrometer_driver.IntegrationTime/1000);
                [data(:,j),wavelength]=app.system_info.Spectrometer_driver.get_spectrum();
                set(app.drawing_handles.spectrum.spectrum,'Ydata',data(:,j),'Xdata',wavelength);
                xlim(app.UIAxes_Spectra, [600 950])
                drawnow;
            end

            filename=[app.file_info.subject_name '_'...
                date_string '_reference_scan.mat'];
            disp(['Saving reference file: ' filename]);
            save(fullfile(app.file_info.output_folder,filename),'data','wavelength')

            %broadband fitting
            data = median(data,2);

            if isempty(app.spectrum_info.reference_spectrum)
                try
                    ref1 = load(fullfile(app.file_info.default_pathname,'..','ReferenceData','ref.mat'));
                    app.spectrum_info.reference_spectrum = ref1.ref_save;
                    date = ref1.ts(1,:);
                    app.ReferencespectrawascollectedonTextArea.Value = date;
                    app.UIAxes_2.cla;
                    plot(app.UIAxes_Spectra,app.spectrum_info.wavelengths,app.spectrum_info.reference_spectrum);
                end
            end


            [WF, HHb, HbO2, a, b] = BF_function(app, wavelength, app.ref, data);

            %calculate tissue saturation
            app.spectrum_info.StO2 = HbO2/(HbO2+HHb)*100;

            app.spectrum_info.Abs.WF = WF;
            app.spectrum_info.Abs.HHb = HHb;
            app.spectrum_info.Abs.HbO2 = HbO2;
            app.spectrum_info.Abs.a = a;
            app.spectrum_info.Abs.b = b;

            app.StartButton.Enable = true;
            app.is_fit_initialized=true;

            app.conc_changes=update_conc_changes(wavelengths);
            app.conc_changes.DPF = app.DPF;
            app.conc_changes.optode_dist=app.PathlengthcmEditField.Value;
            app.conc_changes.use_kalman=true;
            app.conc_changes.raw0=data;


            app.UITable_Spectra_Conc.Visible=true;
            data={'HbO2',HbO2;'HHb',HHb;'StO2',app.spectrum_info.StO2;...
                'Water',WF;'MIE-A',a;'MIE-B',b};
            app.UITable_Spectra_Conc.Data=data;

            app.ReadyLamp.Color = [0, 1, 0];
            app.ReadyLampLabel.Text = 'Ready';

        end

        % Button pushed function: ImportrefButton
        function ImportrefButtonPushed(app, event)
            ref_file = uigetfile();
            ref_data=load(ref_file);
            app.spectrum_info.reference_spectrum = nanmean(ref_data.Spectra,1);
            ts = ref_data.Timestamp;
            ref_save = app.spectrum_info.reference_spectrum;
            save(fullfile(app.file_info.default_pathname,'..','ReferenceData','ref.mat'),'ref_save','ts');

            app.UIAxes_2.cla;
            plot(app.UIAxes_2,app.spectrum_info.wavelengths,app.spectrum_info.reference_spectrum);


        end

        % Button pushed function: MarkEventButton
        function MarkEventButtonPushed(app, event)
            %Add event mark to data on the next write cycle
            str = app.EventDetailsEditField.Value;
            str = ['Mark at ' datestr(now) ' ' str];
            if(isempty(app.MarkEventButton.UserData))
                app.MarkEventButton.UserData=str;
            else
                app.MarkEventButton.UserData=char(app.MarkEventButton.UserData,str);
            end
        end

        % Close request function: MINICYRILUIFigure
        function MINICYRILUIFigureCloseRequest(app, event)

            %warning to check if want to quit
            close_selection = uiconfirm(app.MINICYRILUIFigure,'Are you sure you want to quit?','Close Warning',...
                'Icon','warning','Options',{'Quit','Cancel'},...
                'DefaultOption',2,'CancelOption',2);

            if contains(close_selection,'Quit')
                delete(app)
            end
        end

        % Value changed function: 
        % CorrectForDetectorNonlinearityCheckBox, 
        % CorrectForElectricalDarkNoiseCheckBox, 
        % SetTemperatureCEditField
        function update_instrument(app, event)
            % Send the settings to the instrument.  Note- the driver class will handle if the setting was not allowed, so we ignore
            % which instrument brand here
            app.system_info.Spectrometer_driver.initialize();
            app.system_info.Spectrometer_driver.CorrectForDetectorNonlinearity=app.CorrectForDetectorNonlinearityCheckBox.Value;
            app.system_info.Spectrometer_driver.CorrectForElectricalDark=app.CorrectForElectricalDarkNoiseCheckBox.Value;
            app.system_info.Spectrometer_driver.IntegrationTime=app.IntegrationTimemsEditField.Value;
            app.system_info.Spectrometer_driver.detectorTECSetpointDegC=app.SetTemperatureCEditField.Value;
            
        end

        % Callback function: DPFDropDown, 
        % PathlengthMethodButtonGroup, PathlengthcmEditField, 
        % SeparationcmEditField, WaterFractionEditField
        function update_optical_params(app, event)
            %set up pathlength
            %load DPF and optode distance (DPF from Duncan et al. 1995)
            switch app.DPFDropDown.Value
                case 'Baby Head'
                    app.DPF = 4.99;
                case 'Adult Head'
                    app.DPF = 6.26;
                case 'Adult Arm'
                    app.DPF = 4.16;
                case 'Adult Leg'
                    app.DPF = 5.51;
            end

            app.optode_dist = app.SeparationcmEditField.Value;

            if app.DPFButton.Value ==1

                DPF_code = app.DPF;
                optode_dist_code = app.optode_dist;
                pl =1;
                app.PathlengthcmEditField.Value = DPF_code*app.optode_dist;

            elseif app.NopathlengthButton.Value ==1

                DPF_code = 1;
                optode_dist_code = 1;
                pl = 0;
                app.PathlengthcmEditField.Value = DPF_code*optode_dist_code;

            elseif app.WaterfittingButton.Value ==1

                DPF_code = (app.Abs.WF/app.WaterFractionEditField.Value)*10/app.optode_dist;
                pl = 1;
                optode_dist_code =  app.optode_dist;
                app.PathlengthcmEditField.Value = DPF_code*app.optode_dist;

            end

        end

        % Button pushed function: CollectrefButton
        function CollectRef(app, event)
            Group=app.GroupnameEditField.Value;
            ID=app.SubjectIDEditField.Value;
            Timems=app.IntegrationTimemsEditField.Value;
            h = waitbar(0,'Collecting Reference. Please wait...');
            app.system_info.Spectrometer_driver.IntegrationTime=100;

            [d,wavelength]=app.system_info.Spectrometer_driver.get_spectrum();
            data=zeros(length(d),1)
            data(:,1)=d;
            for i=1:200
                pause(0.1);
                waitbar(i/200,h)
                [data(:,i+1),wavelength]=app.system_info.Spectrometer_driver.get_spectrum();
            end
            delete(h);
                        
            ref1.ref_save=median(data,2);
            ref1.wavelength=wavelength;
            ref1.ts=date;
            mkdir(fullfile(app.file_info.default_pathname,'..','ReferenceData'));
            save(fullfile(app.file_info.default_pathname,'..','ReferenceData','ref.mat'),'-STRUCT','ref1');
            
            app.file_info.output_folder=fullfile(app.file_info.default_pathname, ...
                app.GroupnameEditField.Value,app.SubjectIDEditField.Value);
            
            mkdir(app.file_info.output_folder);
            save(fullfile(app.file_info.output_folder,['ref_' date '.mat']),'-STRUCT','ref1');
            
            
        end

        % Button pushed function: RetryConnectionButton
        function RetryConnectionButtonPushed(app, event)
             %connect to the physiology monitors
            app.physiol_monitor = Physiol;
            app.RetryConnectionButton.Enable=false;
            app.RetryConnectionButton.Visible=false;
            if(~app.physiol_monitor.pulseox_connected)
                warning('Pulse Oximeter not found');
                app.PulseOximeterConnectedLampLabel.Text="Pulse Oximeter Not Found";
                app.PulseOximeterConnectedLamp.Color = [1, 0, 0];
                app.RetryConnectionButton.Enable=true;
                app.RetryConnectionButton.Visible=true;
            else
                app.PulseOximeterConnectedLampLabel.Text="Pulse Oximeter Connected";
                app.PulseOximeterConnectedLamp.Color = [0, 1, 0];
            end
            if(~app.physiol_monitor.capno_connected)
                warning('Capnometer not found');
                app.CapnometerConnectedLampLabel.Text="Capnometer Not Found";
                app.CapnometerConnectedLamp.Color=[1,0,0];
                app.RetryConnectionButton.Enable=true;
                app.RetryConnectionButton.Visible=true;
            else
                app.CapnometerConnectedLampLabel.Text="Capnometer Connected";
                app.CapnometerConnectedLamp.Color=[0,1,0];
            end
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
%             lib = lsl_loadlib();
%             disp('Resolving a Markers stream...');
%             result = {};
%             tic;
%             while isempty(result) & toc<5
%                 result = lsl_resolve_byprop(lib,'type','Markers');
%             end
%             if(~isempty(result))
%                 disp('Opening an inlet...');
%                 app.system_info.inlet = lsl_inlet(result{1});
%                 app.EprimeConnectedLamp.Color=[0 1 0];
%             else
%                 warning("Unable to connect to Eprime");
                 app.system_info.inlet=[];
                 app.EprimeConnectedLamp.Color=[1 0 0];
%             end


            app.ReadyLamp.Color = [0, 0, 1]; %set ready button to blue (running)
            app.ReadyLampLabel.Text = 'Collecting data';

            cla(app.UIAxes_Concentration);
            cla(app.UIAxes_EtCO2);
            cla(app.UIAxes_SO2);
            cla(app.UIAxes_Spectra);

            hold(app.UIAxes_Spectra,'on');
            hold(app.UIAxes_Concentration,'on');
            hold(app.UIAxes_EtCO2,'on');
            hold(app.UIAxes_SO2,'on');

            app.update_instrument();

            % disable the stop
            app.StopButton.Enable=true;
            app.StartButton.Enable=false;
            app.PreviewButton.Enable=false;
            app.MarkEventButton.Enable=true;

            app.saving_data=false;

            try;
                start(app.physiol_monitor);
            catch ME

            end
            if(~app.system_info.is_OceanOptics)
                %check temperature
%                 app.LiveTemperatureCEditField.Value = double(app.system_info.Spectrometer_driver.detectorTemperatureDegC); %display temp
% 
%                 if app.LiveTemperatureCEditField.Value >= -14
% 
%                     temp_selection = uiconfirm(app.MINICYRILUIFigure,'Be aware that the detector temperature is too high. It is recommended that you stop and wait until temperature reaches -15C. Do you want to continue?','Detector Temperature Warning',...
%                         'Icon','warning','Options',{'Continue','Stop'},...
%                         'DefaultOption',2,'CancelOption',2);
% 
%                     if contains(temp_selection,'Stop')==1
%                         app.ReadyLamp.Color = [0, 1, 0];
%                         app.ReadyLampLabel.Text = 'Ready';
%                         return
%                     end
%                 end
            end

            set(app.DataSavedAsEditField,'Value','Not Saving');
            [data,wavelengths]=app.system_info.Spectrometer_driver.get_spectrum();
         

            %Initialize all the drawing lines
            app.drawing_handles=struct;
            t=0;
            app.drawing_handles.conc.hbo=plot(app.UIAxes_Concentration,t,0,'color','r','linewidth',2);
            app.drawing_handles.conc.hbr=plot(app.UIAxes_Concentration,t,0,'color','b','linewidth',2);
            app.drawing_handles.conc.cco=plot(app.UIAxes_Concentration,t,0,'color','g','linewidth',2);

            app.drawing_handles.physio.etco2=plot(app.UIAxes_EtCO2,t,0,'color','k','linewidth',2);
            app.drawing_handles.physio.SpO2=plot(app.UIAxes_SO2,t,0,'color','k','linewidth',2);


            app.drawing_handles.spectrum.spectrum = plot(app.UIAxes_Spectra,wavelengths,data,'Color','k','LineWidth',1);

            if(isempty(app.conc_changes))
                app.conc_changes=update_conc_changes(wavelengths);
                app.conc_changes.DPF = app.DPF;
                app.conc_changes.optode_dist=app.PathlengthcmEditField.Value;
                app.conc_changes.use_kalman=true;
                app.conc_changes.raw0=data;
            end

            if(app.is_fit_initialized)
                app.drawing_handles.spectrum.fit_spectrum = plot(app.UIAxes_Spectra,wavelengths,data,'Color','b','LineWidth',1,'linestyle','--');
            end
            xlim(app.UIAxes_Spectra, [600 950])

            app.drawing_handles.tissue.sto2=plot(app.UIAxes_SO2,t,NaN,'Color','k','LineWidth',1);

            app.drawing_handles.capno.etco2=plot(app.UIAxes_EtCO2,t,NaN,'Color','k','LineWidth',1);
            app.drawing_handles.capno.pulse=plot(app.UIAxes_SO2,t,NaN,'Color','k','LineWidth',1);

            app.system_info.timer = timer('ExecutionMode','fixedRate',...
                'Period',app.IntegrationTimemsEditField.Value/1000,'TimerFcn',@mini_CYRIL_update_data);

            app.stored_data.raw_data=zeros(60*app.maxtime,length(app.spectrum_info.wavelengths));
            app.stored_data.count=1;
            app.stored_data.conc_data=zeros(60*app.maxtime,3);
            app.stored_data.phys_data=cell(60*app.maxtime,1);
            app.stored_data.time=zeros(60*app.maxtime,1);
            app.stored_data.events=zeros(60*app.maxtime,1);
            app.daq_start_time=now;
            start(app.system_info.timer);
        end

        % Value changed function: UseKalmanFilterCheckBox
        function UseKalmanFilterCheckBoxValueChanged(app, event)
            value = app.UseKalmanFilterCheckBox.Value;
            if(app.UseLinearfilterCheckBox.Value==value)
                app.UseLinearfilterCheckBox.Value=~value;
            end
            app.conc_changes.use_kalman=value;
            
            app.NoiseModelindBSpinner.Enable=value;
            app.LowPassHzEditField.Enable=~value;
            app.HighPassHzEditField.Enable=~value;
            app.CheckBox.Enable=~value;
            app.CheckBox_2.Enable=~value;
        end

        % Value changed function: NoiseModelindBSpinner
        function NoiseModelindBSpinnerValueChanged(app, event)
            value = app.NoiseModelindBSpinner.Value;
            app.conc_changes.Q=10^(value/10);
        end

        % Value changed function: UseLinearfilterCheckBox
        function UseLinearfilterCheckBoxValueChanged(app, event)
            value = app.UseLinearfilterCheckBox.Value;
            if(app.UseKalmanFilterCheckBox.Value==value)
                app.UseKalmanfilterCheckBox.Value=~value;
            end
            app.conc_changes.use_kalman=~value;
             
            app.NoiseModelindBSpinner.Enable=~value;
            app.LowPassHzEditField.Enable=value;
            app.HighPassHzEditField.Enable=value;
            app.CheckBox.Enable=value;
            app.CheckBox_2.Enable=value;
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            value = app.CheckBox_2.Value;
            
        end

        % Value changed function: LowPassHzEditField
        function LowPassHzEditFieldValueChanged(app, event)
            value = app.LowPassHzEditField.Value;
            
        end

        % Value changed function: HighPassHzEditField
        function HighPassHzEditFieldValueChanged(app, event)
            value = app.HighPassHzEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MINICYRILUIFigure = uifigure('Visible', 'off');
            app.MINICYRILUIFigure.Name = 'MINICYRIL';
            app.MINICYRILUIFigure.CloseRequestFcn = createCallbackFcn(app, @MINICYRILUIFigureCloseRequest, true);
            app.ReadyLampLabel = uilabel(app.MINICYRILUIFigure);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.MINICYRILUIFigure);
            app.TabGroup = uitabgroup(app.MINICYRILUIFigure);
            app.SpectraTab = uitab(app.TabGroup);
            app.SpectraTab.Title = 'Spectra';
            app.UIAxes_Spectra = uiaxes(app.SpectraTab);
            xlabel(app.UIAxes_Spectra, 'Wavelength (nm)')
            ylabel(app.UIAxes_Spectra, 'Intensity ')
            app.UIAxes_Spectra.YScale = 'log';
            app.ConcentrationsTab = uitab(app.TabGroup);
            app.ConcentrationsTab.Title = 'Concentrations';
            app.UIAxes_Concentration = uiaxes(app.ConcentrationsTab);
            title(app.UIAxes_Concentration, '')
            xlabel(app.UIAxes_Concentration, 'Time (s) ')
            ylabel(app.UIAxes_Concentration, 'Change (mM)')
            app.DisplayWindowsEditFieldLabel = uilabel(app.ConcentrationsTab);
            app.DisplayWindowsEditFieldLabel.Text = 'Display Window (s)';
            app.DisplayWindowsEditField = uieditfield(app.ConcentrationsTab, 'numeric');
            app.DisplayWindowsEditField.Limits = [1 Inf];
            app.DisplayWindowsEditField.Value = 120;
            app.HbO2Label = uilabel(app.ConcentrationsTab);
            app.HbO2Label.Text = 'HbO2';
            app.HbLabel = uilabel(app.ConcentrationsTab);
            app.HbLabel.Text = 'Hb';
            app.CyCOxLabel = uilabel(app.ConcentrationsTab);
            app.CyCOxLabel.Text = 'CyCOx';
            app.CapnometerTab = uitab(app.TabGroup);
            app.CapnometerTab.Title = 'Capnometer';
            app.UIAxes_EtCO2 = uiaxes(app.CapnometerTab);
            title(app.UIAxes_EtCO2, 'End-tidal CO_2')
            xlabel(app.UIAxes_EtCO2, 'time(s)')
            ylabel(app.UIAxes_EtCO2, 'mmHg')
            app.UIAxes_SO2 = uiaxes(app.CapnometerTab);
            title(app.UIAxes_SO2, 'Pulse')
            xlabel(app.UIAxes_SO2, 'time (s)')
            ylabel(app.UIAxes_SO2, 'Pulse Waveform')
            app.EtCO2Label = uilabel(app.CapnometerTab);
            app.EtCO2Label.Text = 'EtCO2';
            app.RespRateLabel = uilabel(app.CapnometerTab);
            app.RespRateLabel.Text = 'Resp Rate';
            app.SO2Label = uilabel(app.CapnometerTab);
            app.SO2Label.Text = 'SO2';
            app.PulseRateLabel = uilabel(app.CapnometerTab);
            app.PulseRateLabel.Text = 'Pulse Rate';
            app.Label_ETCO2_Live = uilabel(app.CapnometerTab);
            app.Label_ETCO2_Live.Text = '??';
            app.Label_RR_Live = uilabel(app.CapnometerTab);
            app.Label_RR_Live.Text = '??';
            app.Label_SaO2_Live = uilabel(app.CapnometerTab);
            app.Label_SaO2_Live.Text = '??';
            app.Label_Pulse_Live = uilabel(app.CapnometerTab);
            app.Label_Pulse_Live.Text = '??';
            app.RealTimeTab = uitab(app.TabGroup);
            app.RealTimeTab.Title = 'Real-Time';
            app.FilterSettingsPanel = uipanel(app.RealTimeTab);
            app.FilterSettingsPanel.Title = 'Filter Settings';
            app.KalmanFilterPanel = uipanel(app.FilterSettingsPanel);
            app.KalmanFilterPanel.Title = 'Kalman Filter';
            app.UseKalmanFilterCheckBox = uicheckbox(app.KalmanFilterPanel);
            app.UseKalmanFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseKalmanFilterCheckBoxValueChanged, true);
            app.UseKalmanFilterCheckBox.Text = 'Use Kalman Filter';
            app.UseKalmanFilterCheckBox.Value = true;
            app.NoiseModelindBLabel = uilabel(app.KalmanFilterPanel);
            app.NoiseModelindBLabel.Text = 'Noise Model (in dB)';
            app.NoiseModelindBSpinner = uispinner(app.KalmanFilterPanel);
            app.NoiseModelindBSpinner.Step = 0.1;
            app.NoiseModelindBSpinner.Limits = [-4 4];
            app.NoiseModelindBSpinner.ValueChangedFcn = createCallbackFcn(app, @NoiseModelindBSpinnerValueChanged, true);
            app.BandPassFilterPanel = uipanel(app.FilterSettingsPanel);
            app.BandPassFilterPanel.Title = 'Band Pass Filter';
            app.UseLinearfilterCheckBox = uicheckbox(app.BandPassFilterPanel);
            app.UseLinearfilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseLinearfilterCheckBoxValueChanged, true);
            app.UseLinearfilterCheckBox.Text = 'Use Linear filter';
            app.LowPassHzEditFieldLabel = uilabel(app.BandPassFilterPanel);
            app.LowPassHzEditFieldLabel.Enable = 'off';
            app.LowPassHzEditFieldLabel.Text = 'Low Pass (Hz)';
            app.LowPassHzEditField = uieditfield(app.BandPassFilterPanel, 'numeric');
            app.LowPassHzEditField.ValueChangedFcn = createCallbackFcn(app, @LowPassHzEditFieldValueChanged, true);
            app.LowPassHzEditField.Value = 0.2;
            app.HighPassHzEditFieldLabel = uilabel(app.BandPassFilterPanel);
            app.HighPassHzEditFieldLabel.Enable = 'off';
            app.HighPassHzEditFieldLabel.Text = 'High Pass (Hz)';
            app.HighPassHzEditField = uieditfield(app.BandPassFilterPanel, 'numeric');
            app.HighPassHzEditField.ValueChangedFcn = createCallbackFcn(app, @HighPassHzEditFieldValueChanged, true);
            app.HighPassHzEditField.Value = 0.008;
            app.CheckBox = uicheckbox(app.BandPassFilterPanel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Enable = 'off';
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.CheckBox_2 = uicheckbox(app.BandPassFilterPanel);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Enable = 'off';
            app.CheckBox_2.Text = '';
            app.CheckBox_2.Value = true;
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.GeneralPanel = uipanel(app.SettingsTab);
            app.GeneralPanel.Title = 'General';
            app.IntegrationTimemsEditFieldLabel = uilabel(app.GeneralPanel);
            app.IntegrationTimemsEditFieldLabel.Text = 'Integration Time (ms)';
            app.IntegrationTimemsEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.IntegrationTimemsEditField.LowerLimitInclusive = 'off';
            app.IntegrationTimemsEditField.Limits = [0 12000];
            app.IntegrationTimemsEditField.Value = 1000;
            app.ImportrefButton = uibutton(app.GeneralPanel, 'push');
            app.ImportrefButton.ButtonPushedFcn = createCallbackFcn(app, @ImportrefButtonPushed, true);
            app.ImportrefButton.Text = 'Import ref';
            app.ReferencespectrawascollectedonTextArea = uitextarea(app.GeneralPanel);
            app.ReferencespectrawascollectedonTextArea.Value = {'NO REFERENCE LOADED'};
            app.ReferencespectrawascollectedonTextAreaLabel = uilabel(app.GeneralPanel);
            app.ReferencespectrawascollectedonTextAreaLabel.Text = 'Reference spectra was collected on:';
            app.CollectrefButton = uibutton(app.GeneralPanel, 'push');
            app.CollectrefButton.ButtonPushedFcn = createCallbackFcn(app, @CollectRef, true);
            app.CollectrefButton.Text = 'Collect ref';
            app.PathlengthPanel = uipanel(app.SettingsTab);
            app.PathlengthPanel.Title = 'Pathlength';
            app.PathlengthPanel.Visible = 'off';
            app.PathlengthMethodButtonGroup = uibuttongroup(app.PathlengthPanel);
            app.PathlengthMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @update_optical_params, true);
            app.PathlengthMethodButtonGroup.Title = 'Pathlength Method';
            app.DPFButton = uiradiobutton(app.PathlengthMethodButtonGroup);
            app.DPFButton.Text = 'DPF';
            app.DPFButton.Value = true;
            app.NopathlengthButton = uiradiobutton(app.PathlengthMethodButtonGroup);
            app.NopathlengthButton.Text = 'No pathlength';
            app.WaterfittingButton = uiradiobutton(app.PathlengthMethodButtonGroup);
            app.WaterfittingButton.Text = 'Water fitting';
            app.SeparationcmEditFieldLabel = uilabel(app.PathlengthPanel);
            app.SeparationcmEditFieldLabel.Text = ' Separation (cm)';
            app.SeparationcmEditField = uieditfield(app.PathlengthPanel, 'numeric');
            app.SeparationcmEditField.Limits = [0.1 10];
            app.SeparationcmEditField.ValueChangedFcn = createCallbackFcn(app, @update_optical_params, true);
            app.SeparationcmEditField.Value = 3;
            app.DPFDropDownLabel = uilabel(app.PathlengthPanel);
            app.DPFDropDownLabel.Text = 'DPF';
            app.DPFDropDown = uidropdown(app.PathlengthPanel);
            app.DPFDropDown.Items = {'Baby Head', 'Adult Head', 'Adult Arm', 'Adult Leg'};
            app.DPFDropDown.ValueChangedFcn = createCallbackFcn(app, @update_optical_params, true);
            app.DPFDropDown.Value = 'Adult Head';
            app.WaterFractionEditFieldLabel = uilabel(app.PathlengthPanel);
            app.WaterFractionEditFieldLabel.Text = 'Water Fraction';
            app.WaterFractionEditField = uieditfield(app.PathlengthPanel, 'numeric');
            app.WaterFractionEditField.Limits = [0 1];
            app.WaterFractionEditField.ValueChangedFcn = createCallbackFcn(app, @update_optical_params, true);
            app.WaterFractionEditField.Value = 0.85;
            app.TemperatureWasatchPhotonicsOnlyPanel = uipanel(app.SettingsTab);
            app.TemperatureWasatchPhotonicsOnlyPanel.Title = 'Temperature (Wasatch Photonics Only)';
            app.SetTemperatureCEditFieldLabel = uilabel(app.TemperatureWasatchPhotonicsOnlyPanel);
            app.SetTemperatureCEditFieldLabel.Text = 'Set Temperature (C)';
            app.SetTemperatureCEditField = uieditfield(app.TemperatureWasatchPhotonicsOnlyPanel, 'numeric');
            app.SetTemperatureCEditField.Limits = [-20 20];
            app.SetTemperatureCEditField.ValueChangedFcn = createCallbackFcn(app, @update_instrument, true);
            app.SetTemperatureCEditField.Value = -15;
            app.LiveTemperatureCEditFieldLabel = uilabel(app.TemperatureWasatchPhotonicsOnlyPanel);
            app.LiveTemperatureCEditFieldLabel.Text = 'Live Temperature (C)';
            app.LiveTemperatureCEditField = uieditfield(app.TemperatureWasatchPhotonicsOnlyPanel, 'numeric');
            app.LiveTemperatureCEditField.Limits = [-40 40];
            app.LiveTemperatureCEditField.Value = -15;
            app.PathlengthcmEditFieldLabel = uilabel(app.SettingsTab);
            app.PathlengthcmEditFieldLabel.Enable = 'off';
            app.PathlengthcmEditFieldLabel.Visible = 'off';
            app.PathlengthcmEditFieldLabel.Text = 'Pathlength (cm)';
            app.PathlengthcmEditField = uieditfield(app.SettingsTab, 'numeric');
            app.PathlengthcmEditField.ValueChangedFcn = createCallbackFcn(app, @update_optical_params, true);
            app.PathlengthcmEditField.Enable = 'off';
            app.PathlengthcmEditField.Visible = 'off';
            app.CorrectionsOceanOpticsOnlyPanel = uipanel(app.SettingsTab);
            app.CorrectionsOceanOpticsOnlyPanel.Title = 'Corrections (Ocean Optics Only)';
            app.CorrectForDetectorNonlinearityCheckBox = uicheckbox(app.CorrectionsOceanOpticsOnlyPanel);
            app.CorrectForDetectorNonlinearityCheckBox.ValueChangedFcn = createCallbackFcn(app, @update_instrument, true);
            app.CorrectForDetectorNonlinearityCheckBox.Text = 'Correct For Detector Nonlinearity';
            app.CorrectForDetectorNonlinearityCheckBox.Value = true;
            app.CorrectForElectricalDarkNoiseCheckBox = uicheckbox(app.CorrectionsOceanOpticsOnlyPanel);
            app.CorrectForElectricalDarkNoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @update_instrument, true);
            app.CorrectForElectricalDarkNoiseCheckBox.Text = 'Correct For Electrical Dark Noise';
            app.ControlsPanel = uipanel(app.MINICYRILUIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.StartButton = uibutton(app.ControlsPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.MarkEventButton = uibutton(app.ControlsPanel, 'push');
            app.MarkEventButton.ButtonPushedFcn = createCallbackFcn(app, @MarkEventButtonPushed, true);
            app.MarkEventButton.Enable = 'off';
            app.MarkEventButton.Text = 'Mark Event';
            app.StopButton = uibutton(app.ControlsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.EventDetailsEditFieldLabel = uilabel(app.ControlsPanel);
            app.EventDetailsEditFieldLabel.Text = 'Event Details';
            app.EventDetailsEditField = uieditfield(app.ControlsPanel, 'text');
            app.GroupnameEditFieldLabel = uilabel(app.ControlsPanel);
            app.GroupnameEditFieldLabel.Text = 'Group name';
            app.GroupnameEditField = uieditfield(app.ControlsPanel, 'text');
            app.GroupnameEditField.Value = 'MIND';
            app.SubjectIDEditFieldLabel = uilabel(app.ControlsPanel);
            app.SubjectIDEditFieldLabel.Text = 'Subject ID';
            app.SubjectIDEditField = uieditfield(app.ControlsPanel, 'text');
            app.SubjectIDEditField.Value = '1';
            app.PreviewButton = uibutton(app.ControlsPanel, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.CommentsTextAreaLabel = uilabel(app.ControlsPanel);
            app.CommentsTextAreaLabel.Text = 'Comments';
            app.CommentsTextArea = uitextarea(app.ControlsPanel);
            app.miniCYRILforFAMPATHLabel = uilabel(app.MINICYRILUIFigure);
            app.miniCYRILforFAMPATHLabel.Text = 'miniCYRIL for FAMPATH';
            app.LiveTimesEditFieldLabel = uilabel(app.MINICYRILUIFigure);
            app.LiveTimesEditFieldLabel.Text = 'Live Time (s)';
            app.LiveTimesEditField = uieditfield(app.MINICYRILUIFigure, 'numeric');
            app.DataSavedAsEditFieldLabel = uilabel(app.MINICYRILUIFigure);
            app.DataSavedAsEditFieldLabel.Text = 'Data Saved As:';
            app.DataSavedAsEditField = uieditfield(app.MINICYRILUIFigure, 'text');
            app.DataSavedAsEditField.Editable = 'off';
            app.CapnometerConnectedLampLabel = uilabel(app.MINICYRILUIFigure);
            app.CapnometerConnectedLampLabel.Text = 'Capnometer Connected';
            app.CapnometerConnectedLamp = uilamp(app.MINICYRILUIFigure);
            app.PulseOximeterConnectedLampLabel = uilabel(app.MINICYRILUIFigure);
            app.PulseOximeterConnectedLampLabel.Text = 'Pulse Oximeter Connected';
            app.PulseOximeterConnectedLamp = uilamp(app.MINICYRILUIFigure);
            app.RetryConnectionButton = uibutton(app.MINICYRILUIFigure, 'push');
            app.RetryConnectionButton.ButtonPushedFcn = createCallbackFcn(app, @RetryConnectionButtonPushed, true);
            app.RetryConnectionButton.Enable = 'off';
            app.RetryConnectionButton.Visible = 'off';
            app.RetryConnectionButton.Text = 'Retry Connection';
            app.EprimeConnectedLampLabel = uilabel(app.MINICYRILUIFigure);
            app.EprimeConnectedLampLabel.Text = 'Eprime Connected';
            app.EprimeConnectedLamp = uilamp(app.MINICYRILUIFigure);
            % Show the figure after all components are created
            app.MINICYRILUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = miniCYRIL_at_Columbia

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MINICYRILUIFigure)

            % Execute the startup function
            runStartupFcn(app, @getStarted)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MINICYRILUIFigure)
        end
    end
end